classdef ms_study_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        InitVarsButton                 matlab.ui.control.Button
        FindLoadCellBiasButton         matlab.ui.control.Button
        WeightButton                   matlab.ui.control.Button
        MVCButton                      matlab.ui.control.Button
        CoPPanel                       matlab.ui.container.Panel
        TrialTimeEditFieldLabel        matlab.ui.control.Label
        TrialTime                      matlab.ui.control.EditField
        DPStiffnessEditFieldLabel      matlab.ui.control.Label
        DPStiffness                    matlab.ui.control.EditField
        IEStiffnessEditFieldLabel      matlab.ui.control.Label
        IEStiffness                    matlab.ui.control.EditField
        VisualSwitchLabel              matlab.ui.control.Label
        VisualSwitch                   matlab.ui.control.Switch
        StartCoPTrialsButton           matlab.ui.control.Button
        LoadSLXFilesButton             matlab.ui.control.Button
        SetParamsButton                matlab.ui.control.Button
        DataFileNameEditFieldLabel     matlab.ui.control.Label
        DataFileName                   matlab.ui.control.EditField
        WalkingPanel                   matlab.ui.container.Panel
        DirectionButtonGroup           matlab.ui.container.ButtonGroup
        DPButton_Walking               matlab.ui.control.RadioButton
        IEButton_Walking               matlab.ui.control.RadioButton
        StandingPanel                  matlab.ui.container.Panel
        DirectionButtonGroup_2         matlab.ui.container.ButtonGroup
        DPButton_Standing              matlab.ui.control.RadioButton
        IEButton_Standing              matlab.ui.control.RadioButton
        PlatformButtonGroup            matlab.ui.container.ButtonGroup
        LeftButton                     matlab.ui.control.RadioButton
        RightButton                    matlab.ui.control.RadioButton
        DataCollectionTypeButtonGroup  matlab.ui.container.ButtonGroup
        CoPButton                      matlab.ui.control.RadioButton
        StandingImpedanceButton        matlab.ui.control.RadioButton
        WalkingImpedanceButton         matlab.ui.control.RadioButton
        RunButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitVarsButton
        function InitVarsButtonPushed(app, event)
            amplitude=0;
            phi=3.14;
            off_TA = 0;
            off_SOL = 0;
            TA_MVC = 1;
            SOL_MVC = 1;
            TMVC = 0;
            Ts=0.0005;
            trial=0;
            range=0;
            DPbase=0;
            IEbase=0;
            forrange=5;
            maxtorquediff=50;
            
            f1_off=0;
            f2_off=0;
            f3_off=0;
            f4_off=0;
            f5_off=0;
            f6_off=0;
            f7_off=0;
            f8_off=0;
            
            f1_off_L=0;
            f2_off_L=0;
            f3_off_L=0;
            f4_off_L=0;
            f5_off_L=0;
            f6_off_L=0;
            f7_off_L=0;
            f8_off_L=0;
            
            kdp=1000;
            kie=1000;
            
            assignin('base','amplitude',amplitude);
            assignin('base','phi',phi);
            assignin('base','off_TA',off_TA);
            assignin('base','off_SOL',off_SOL);
            assignin('base','TA_MVC',TA_MVC);
            assignin('base','SOL_MVC',SOL_MVC);
            assignin('base','TMVC',TMVC);
            assignin('base','Ts',Ts);
            assignin('base','trial',trial);
            assignin('base','range',range);
            assignin('base','DPbase',DPbase);
            assignin('base','IEbase',IEbase);
            assignin('base','forrange',forrange);
            assignin('base','maxtorquediff',maxtorquediff);
            
            assignin('base','f1_off',f1_off);
            assignin('base','f2_off',f2_off);
            assignin('base','f3_off',f3_off);
            assignin('base','f4_off',f4_off);
            assignin('base','f5_off',f5_off);
            assignin('base','f6_off',f6_off);
            assignin('base','f7_off',f7_off);
            assignin('base','f8_off',f8_off);
            
            
            assignin('base','f1_off_L',f1_off_L);
            assignin('base','f2_off_L',f2_off_L);
            assignin('base','f3_off_L',f3_off_L);
            assignin('base','f4_off_L',f4_off_L);
            assignin('base','f5_off_L',f5_off_L);
            assignin('base','f6_off_L',f6_off_L);
            assignin('base','f7_off_L',f7_off_L);
            assignin('base','f8_off_L',f8_off_L);
            
            assignin('base','kdp',kdp);
            assignin('base','kie',kie);
        end

        % Button pushed function: MVCButton
        function MVCButtonPushed(app, event)
            
            if (app.RightButton.Value)
                mvc_filename = 'MVC.DAT'
            elseif (app.LeftButton.Value)
                mvc_filename = 'MVC_L.DAT'
            end
          
            [mvc_data] = mvc_reflex(mvc_filename);
            assignin('base','off_TA', mvc_data.emg.off_TA);
            assignin('base','off_SOL',mvc_data.emg.off_SOL);
            assignin('base','off_PL', mvc_data.emg.off_PL);
            assignin('base','off_GCA',mvc_data.emg.off_GCA);
            
            assignin('base','TA_MVC', mvc_data.emg.TA_MVC);
            assignin('base','SOL_MVC',mvc_data.emg.SOL_MVC);
            assignin('base','PL_MVC', mvc_data.emg.SOL_MVC);
            assignin('base','GCA_MVC',mvc_data.emg.GCA_MVC);
            
            assignin('base','DPbase', mvc_data.DPbase);
            assignin('base','IEbase', mvc_data.IEbase);
            assignin('base','CoPbase',mvc_data.CoPbase);
            assignin('base','single_weight', mvc_data.single_weight);
    
            assignin('base','DP_cop', mvc_data.DP_cop);
            assignin('base','IE_cop', mvc_data.IE_cop);
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if(app.CoPButton.Value==1)
                
                kdp = str2double(app.DPStiffness.Value);
                kie=str2double(app.IEStiffness.Value);
                
                assignin('base','kdp',kdp);
                assignin('base','kie',kie);
                
                
                if(app.VisualSwitch.Value=="Off")
                    visual=0;
                    assignin('base','visual',visual);
                elseif(app.VisualSwitch.Value=="On")
                    visual=1;
                    assignin('base','visual',visual);
                end
               
                ttime=2000*str2double(app.TrialTime.Value);
                assignin('base','ttime',ttime);
            
            end
            
            
            if(app.StandingImpedanceButton.Value==1)
                if(app.DPButton_Standing.Value==1)
                    act = 0;
                    trial = 1;
                    range=1;
                    assignin('base','TMVC',act);
                    assignin('base','trial',trial);
                    assignin('base','range',range);
                    
                    %This is actually CoP_Torque i.e. the torque needed to get a specfic CoP for a specific weight
                    %It is used to reposition the CoP if it is desired to have it at another value other than the baseline
                    %In the MS studies however, we desire it to be zero.
                    CoP = 0;
                    assignin('base','CoP',CoP);
                    
                    DP_cop  = evalin('base','DP_cop');
                    CoPbase = evalin('base','CoPbase');
                    IE_cop  = evalin('base','IE_cop');
                    tweight = evalin('base', 'single_weight');
                    
                    %Finds bias torque to make CoP centered at desired location                        
                    IEF=IE_cop*tweight*0.01;
                    DPF=(CoP+DP_cop)*(tweight)*0.01;
                    
                    assignin('base','DPF',DPF);
                    assignin('base','IEF',IEF);
                    assignin('base','tweight',tweight);
                    
                    
                end
                if(app.IEButton_Standing.Value==1)
                    act = 0;
                    trial = 2;
                    range = 1;
                    assignin('base','TMVC',act);
                    assignin('base','trial',trial);
                    assignin('base','range',range);
                    
                    
                    %This is actually CoP_Torque i.e. the torque needed to get a specfic CoP for a specific weight
                    %It is used to reposition the CoP if it is desired to have it at another value other than the baseline
                    %In the MS studies however, we desire it to be zero.
                    CoP = 0;
                    assignin('base','CoP',CoP);
                    
                    DP_cop  = evalin('base','DP_cop');
                    CoPbase = evalin('base','CoPbase');
                    IE_cop  = evalin('base','IE_cop');
                    tweight = evalin('base', 'single_weight');
                        
                    IEF=(CoP+IE_cop)*tweight*0.01;
                    DPF=(DP_cop)*(tweight)*0.01;
                                            
                    assignin('base','DPF',DPF);
                    assignin('base','IEF',IEF);
                    assignin('base','tweight',tweight);
                end
                
            end
            if(app.WalkingImpedanceButton.Value==1)
                if(app.DPButton_Walking.Value==1)
                    act = 0;
                    trial = 1;
                    range=1;
                    
                    assignin('base','TMVC',act);
                    assignin('base','trial',trial);
                    assignin('base','range',range);
                    
                    CoP=0;
                    assignin('base','CoP',CoP);
                    
                    weight=evalin('base','weight');
                    DP_cop = evalin('base','DP_cop');
                    CoPbase=evalin('base','CoPbase');
                    IE_cop = evalin('base','IE_cop');
                    weight=evalin('base','weight');
                    tweight=50*0.01*weight;
                        
                    IEF=IE_cop*tweight*0.01;
                    DPF=(CoP+DP_cop)*(tweight)*0.01;
                    
                    assignin('base','DPF',DPF);
                    assignin('base','IEF',IEF);
                    assignin('base','tweight',tweight);
                    
                    
                end
                if(app.IEButton_Walking.Value==1)
                    act = 0;
                    trial = 1;
                    range=1;
                    
                    assignin('base','TMVC',act);
                    assignin('base','trial',trial);
                    assignin('base','range',range);
                    
                    CoP=0;
                    assignin('base','CoP',CoP);
                    
                    weight=evalin('base','weight');
                    DP_cop = evalin('base','DP_cop');
                    CoPbase=evalin('base','CoPbase');
                    IE_cop = evalin('base','IE_cop');
                    weight=evalin('base','weight');
                    tweight=50*0.01*weight;
                        
                    IEF=IE_cop*tweight*0.01;
                    DPF=(CoP+DP_cop)*(tweight)*0.01;
                    
                    assignin('base','DPF',DPF);
                    assignin('base','IEF',IEF);
                    assignin('base','tweight',tweight);
                end
                
            end

        end

        % Button pushed function: WeightButton
        function WeightButtonPushed(app, event)
            weightcalc;
            assignin('base','tweight',tweight);
            assignin('base','weight',weight);
        end

        % Button pushed function: FindLoadCellBiasButton
        function FindLoadCellBiasButtonPushed(app, event)
            right_loadcell_bias = pbase('PBASE.DAT');
            assignin('base','f1_off',right_loadcell_bias.f1_off);
            assignin('base','f2_off',right_loadcell_bias.f2_off);
            assignin('base','f3_off',right_loadcell_bias.f3_off);
            assignin('base','f4_off',right_loadcell_bias.f4_off);
            assignin('base','f5_off',right_loadcell_bias.f5_off);
            assignin('base','f6_off',right_loadcell_bias.f6_off);
            assignin('base','f7_off',right_loadcell_bias.f7_off);
            assignin('base','f8_off',right_loadcell_bias.f8_off);
            
            left_loadcell_bias = pbase('PBASE_L.DAT');
            assignin('base','f1_off_L',left_loadcell_bias.f1_off);
            assignin('base','f2_off_L',left_loadcell_bias.f2_off);
            assignin('base','f3_off_L',left_loadcell_bias.f3_off);
            assignin('base','f4_off_L',left_loadcell_bias.f4_off);
            assignin('base','f5_off_L',left_loadcell_bias.f5_off);
            assignin('base','f6_off_L',left_loadcell_bias.f6_off);
            assignin('base','f7_off_L',left_loadcell_bias.f7_off);
            assignin('base','f8_off_L',left_loadcell_bias.f8_off);
        end

        % Button pushed function: StartCoPTrialsButton
        function StartCoPTrialsButtonPushed(app, event)
            
            %% Get software objects representing the devices
            
            right_unit = slrt("Right_Platform"); %These should match the Real-Time Units names' 
            left_unit = slrt("Left_Platform");   %seen in slrtexplr
            
            %% Start the devices
            right_unit.start();
            left_unit.start();
        end

        % Button pushed function: LoadSLXFilesButton
        function LoadSLXFilesButtonPushed(app, event)
            right_unit = slrt("Right_Platform"); %These should match the Real-Time Units names' 
            left_unit = slrt("Left_Platform");   %seen in slrtexplr
            
            %% Load Project Files onto the devices
            right_unit.load('CoP_Master');
            left_unit.load('CoP_Master_Left');
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
        
            kdp = str2double(app.DPStiffness.Value);
            kie=str2double(app.IEStiffness.Value);
            ttime=2000*str2double(app.TrialTime.Value);
        
            right_unit = slrt("Right_Platform"); %These should match the Real-Time Units names' 
            left_unit = slrt("Left_Platform");   %seen in slrtexplr
            
            %% Set trial time
            % Right platform
            setparam(right_unit, 'ttime', ttime);
            % Left platform
            setparam(left_unit, 'ttime', ttime);
            
            %% Set stiffness value
            % Right platform
            setparam(right_unit, 'kdp', kdp); % DP
            setparam(right_unit, 'kie', kie); % IE
            
            % Left platform
            setparam(left_unit, 'kdp', kdp); % DP
            setparam(left_unit, 'kie', kie); % IE

            %% Set File Names
            r_file_name = strcat(app.DataFileName.Value, "R.DAT");
            l_file_name = strcat(app.DataFileName.Value, "L.DAT");
            
            % Right Platform
            R_file_scope_obj = getscope(right_unit, 3);
            R_file_scope_obj.Filename = r_file_name;
            
            % Left Platform
            L_file_scope_obj = getscope(left_unit, 3);
            L_file_scope_obj.Filename = l_file_name;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.InitVarsButton = uibutton(app.UIFigure, 'push');
            app.InitVarsButton.ButtonPushedFcn = createCallbackFcn(app, @InitVarsButtonPushed, true);
            app.InitVarsButton.Text = 'Init Vars';
            app.FindLoadCellBiasButton = uibutton(app.UIFigure, 'push');
            app.FindLoadCellBiasButton.ButtonPushedFcn = createCallbackFcn(app, @FindLoadCellBiasButtonPushed, true);
            app.FindLoadCellBiasButton.Text = 'Find Load Cell Bias';
            app.WeightButton = uibutton(app.UIFigure, 'push');
            app.WeightButton.ButtonPushedFcn = createCallbackFcn(app, @WeightButtonPushed, true);
            app.WeightButton.Text = 'Weight';
            app.MVCButton = uibutton(app.UIFigure, 'push');
            app.MVCButton.ButtonPushedFcn = createCallbackFcn(app, @MVCButtonPushed, true);
            app.MVCButton.Text = 'MVC ';
            app.CoPPanel = uipanel(app.UIFigure);
            app.CoPPanel.Title = 'CoP';
            app.TrialTimeEditFieldLabel = uilabel(app.CoPPanel);
            app.TrialTimeEditFieldLabel.Text = 'Trial Time';
            app.TrialTime = uieditfield(app.CoPPanel, 'text');
            app.DPStiffnessEditFieldLabel = uilabel(app.CoPPanel);
            app.DPStiffnessEditFieldLabel.Text = 'DP Stiffness';
            app.DPStiffness = uieditfield(app.CoPPanel, 'text');
            app.IEStiffnessEditFieldLabel = uilabel(app.CoPPanel);
            app.IEStiffnessEditFieldLabel.Text = 'IE Stiffness';
            app.IEStiffness = uieditfield(app.CoPPanel, 'text');
            app.VisualSwitchLabel = uilabel(app.CoPPanel);
            app.VisualSwitchLabel.Text = 'Visual';
            app.VisualSwitch = uiswitch(app.CoPPanel, 'slider');
            app.StartCoPTrialsButton = uibutton(app.CoPPanel, 'push');
            app.StartCoPTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @StartCoPTrialsButtonPushed, true);
            app.StartCoPTrialsButton.Text = 'Start CoP Trials';
            app.LoadSLXFilesButton = uibutton(app.CoPPanel, 'push');
            app.LoadSLXFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSLXFilesButtonPushed, true);
            app.LoadSLXFilesButton.Text = 'Load SLX Files';
            app.SetParamsButton = uibutton(app.CoPPanel, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Text = 'Set Params';
            app.DataFileNameEditFieldLabel = uilabel(app.CoPPanel);
            app.DataFileNameEditFieldLabel.Text = 'Data File Name';
            app.DataFileName = uieditfield(app.CoPPanel, 'text');
            app.WalkingPanel = uipanel(app.UIFigure);
            app.WalkingPanel.Title = 'Walking';
            app.DirectionButtonGroup = uibuttongroup(app.WalkingPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.DPButton_Walking = uiradiobutton(app.DirectionButtonGroup);
            app.DPButton_Walking.Text = 'DP';
            app.IEButton_Walking = uiradiobutton(app.DirectionButtonGroup);
            app.IEButton_Walking.Text = 'IE';
            app.IEButton_Walking.Value = true;
            app.StandingPanel = uipanel(app.UIFigure);
            app.StandingPanel.Title = 'Standing';
            app.DirectionButtonGroup_2 = uibuttongroup(app.StandingPanel);
            app.DirectionButtonGroup_2.Title = 'Direction';
            app.DPButton_Standing = uiradiobutton(app.DirectionButtonGroup_2);
            app.DPButton_Standing.Text = 'DP';
            app.DPButton_Standing.Value = true;
            app.IEButton_Standing = uiradiobutton(app.DirectionButtonGroup_2);
            app.IEButton_Standing.Text = 'IE';
            app.PlatformButtonGroup = uibuttongroup(app.StandingPanel);
            app.PlatformButtonGroup.Title = 'Platform';
            app.LeftButton = uiradiobutton(app.PlatformButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.PlatformButtonGroup);
            app.RightButton.Text = 'Right';
            app.DataCollectionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DataCollectionTypeButtonGroup.Title = 'Data Collection Type';
            app.CoPButton = uiradiobutton(app.DataCollectionTypeButtonGroup);
            app.CoPButton.Text = 'CoP';
            app.CoPButton.Value = true;
            app.StandingImpedanceButton = uiradiobutton(app.DataCollectionTypeButtonGroup);
            app.StandingImpedanceButton.Text = 'Standing Impedance';
            app.WalkingImpedanceButton = uiradiobutton(app.DataCollectionTypeButtonGroup);
            app.WalkingImpedanceButton.Text = 'Walking Impedance';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ms_study_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end